classdef lesionView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LesionVisibilitySwitch   matlab.ui.control.Switch
        LesionIndexSpinner       matlab.ui.control.Spinner
        Spinner                  matlab.ui.control.Spinner
        Spinner_3                matlab.ui.control.Spinner
        Spinner_2                matlab.ui.control.Spinner
        PlayPauseButton          matlab.ui.control.Button
        Panel_2                  matlab.ui.container.Panel
        BIDSButton               matlab.ui.control.Button
        LoadBrainMaskButton      matlab.ui.control.Button
        Loadlesion2Button        matlab.ui.control.Button
        Loadlesion1Button        matlab.ui.control.Button
        LoadT2FlairTP2Button     matlab.ui.control.Button
        LoadT2FlairTP1Button     matlab.ui.control.Button
        LesionVisualizationAxes  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        x0 double = 100; % Default value, adjust as needed
        y0 double = 120; % Default value, adjust as needed
        z0 double = 180; % Default value, adjust as needed
        sizeZoom double = 26; % Zoom window size, adjust as needed
        whichT2 % The variable to hold your 3D image data
        T2FlairTP1 % T2 flair image at time point 1
        T2FlairTP2 % T2 flair image at time point 2
        Lesion1 % Lesion image at time point 1
        Lesion2 % Lesion image at time point 2
        L1 % Lesion image at time point 1
        L2 % Lesion image at time point 2
        brainMask
        ImageSwitchTimer % Timer for switching images
        CurrentImageIndex % 1 for T2FlairTP1, 2 for T2FlairTP2
        T2Loaded = [false, false]; % Tracks whether each T2 image has been loaded
        voxelSize = 1; % Assuming 1, adjust based on your data
        sizeChange % To store the size change results
        whichLesion = 1;
        lesionIndex; % each unique lesion has an index
        lesionPatches;
        brainMaskPatch;
        lesionCenter;

    end

    methods (Access = private)

        function updateImage(app)

            % Extract slices for the bottom row images
            img21 = mat2gray(squeeze(app.whichT2(app.x0,:,:)))';
            img22 = mat2gray(squeeze(app.whichT2(:,app.y0,:)))';
            img23 = mat2gray(squeeze(app.whichT2(:,:,app.z0)));

            % Combine them horizontally
            botrow = [img21 img22 img23];

            % Calculate padding to ensure the top row images are centered
            padding = (size(botrow,2) - size(botrow,1)*3) / 2;

            % Extract and resize the zoomed-in images for the top row
            img11 = imresize(img21(max(1, app.y0 - app.sizeZoom):min(end, app.y0 + app.sizeZoom), max(1, app.z0 - app.sizeZoom):min(end, app.z0 + app.sizeZoom)), [size(botrow,1) size(botrow,1)]);
            img12 = imresize(img22(max(1, app.x0 - app.sizeZoom):min(end, app.x0 + app.sizeZoom), max(1, app.z0 - app.sizeZoom):min(end, app.z0 + app.sizeZoom)), [size(botrow,1) size(botrow,1)]);
            img13 = imresize(img23(max(1, app.y0 - app.sizeZoom):min(end, app.y0 + app.sizeZoom), max(1, app.x0 - app.sizeZoom):min(end, app.x0 + app.sizeZoom)), [size(botrow,1) size(botrow,1)]);

            % Combine the top row images with padding
            toprow = [zeros(size(botrow,1), floor(padding)-20), img11, zeros(size(botrow,1), 20), img12, zeros(size(botrow,1), 20), img13, zeros(size(botrow,1), ceil(padding)-20)];

            % Combine top and bottom rows
            img = [toprow; botrow];

            % Display the combined image in UIAxes
            imshow(img, 'Parent', app.UIAxes);
            app.UIAxes.Visible = 'off'; % Optionally hide the axes
        end

        function setupImageSwitchTimer(app)
            app.ImageSwitchTimer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.5, ...
                'TimerFcn', @(~,~)toggleT2Image(app));
        end

        function toggleT2Image(app)
            
            % Toggle the index between 1 and 2
            if app.CurrentImageIndex == 1
                app.CurrentImageIndex = 2;
                app.whichT2 = app.T2FlairTP2; % Set to second image
            else
                app.CurrentImageIndex = 1;
                app.whichT2 = app.T2FlairTP1; % Set back to first image
            end

            updateImage(app); % Refresh the T2 flair image display

            % Assuming you have a function updateLesionVisibility that controls the visibility of lesions
            updateLesionVisibility(app, app.LesionIndexSpinner.Value, app.CurrentImageIndex);
        end


        function analyzeLesions(app)
            
                someThresholdDistance = 3.5; % Define your threshold distance

                % Label and calculate lesion volumes
                [L1, num1] = bwlabeln(app.Lesion1 > 0.8);
                [L2, num2] = bwlabeln(app.Lesion2 > 0.8);

                % Calculate lesion properties for centroids
                props1 = regionprops(L1, 'Centroid');
                props2 = regionprops(L2, 'Centroid');

                % Initialize an array to hold the new indices for lesions in Time 2
                newIndices = zeros(size(L2));

                % Match lesions based on centroid proximity
                for i = 1:num1
                    centroid1 = props1(i).Centroid;

                    % Find the closest lesion in Time 2
                    minDistance = inf;
                    matchedIndex = 0;
                    for j = 1:num2
                        centroid2 = props2(j).Centroid;
                        distance = norm(centroid1 - centroid2);

                        if distance < minDistance
                            minDistance = distance;
                            matchedIndex = j;
                        end
                    end

                    % Assign the same index if a match is found within a threshold distance
                    if minDistance < someThresholdDistance && matchedIndex > 0
                        newIndices(L2 == matchedIndex) = i;
                    end
                end

                % Assign new indices to unmatched lesions
                currentMaxIndex = num1;
                for j = 1:num2
                    if ~any(newIndices == j) & nnz(L2 == j) > 0  % Use element-wise & if needed, but here scalar logic is intended
                        currentMaxIndex = currentMaxIndex + 1;
                        newIndices(L2 == j) = currentMaxIndex;
                    end
                end

                % Update L2 with the new indices
                app.L2 = newIndices;
                app.L1 = L1;
                app.lesionIndex = nonzeros(unique(app.L2));
                app.lesionCenter = zeros(numel(app.lesionIndex),3);

                for ii = 1:numel(app.lesionIndex)
                    binaryMat = app.L1 == app.lesionIndex(ii); % Example binary matrix
                    if sum(binaryMat(:)) < 1
                        binaryMat = app.L2 == app.lesionIndex(ii);
                    end
                    % Find the index of the maximum sum for each dimension
                    [~, app.lesionCenter(ii,1)] = max(squeeze(sum(sum(binaryMat, 2), 3)));
                    [~, app.lesionCenter(ii,2)] = max(squeeze(sum(sum(binaryMat, 1), 3)));
                    [~, app.lesionCenter(ii,3)] = max(squeeze(sum(sum(binaryMat, 1), 2)));
                end


        end

        function drawBrainMask(app)
            if ~isempty(app.brainMask)
                [x, y, z] = meshgrid(1:size(app.brainMask,2), 1:size(app.brainMask,1), 1:size(app.brainMask,3));
                [f, v] = isosurface(x, y, z, app.brainMask, 0.5);
                app.brainMaskPatch = patch(app.LesionVisualizationAxes, 'Faces', f, 'Vertices', v, 'FaceColor', [0.5 0.5 0.5], 'EdgeColor', 'none', 'FaceAlpha', 0.05);
                view(app.LesionVisualizationAxes, 3);
                axis(app.LesionVisualizationAxes, 'equal');
                camlight(app.LesionVisualizationAxes, 'headlight');
                lighting(app.LesionVisualizationAxes, 'gouraud');
            end
        end

        function drawLesions(app)
            [x, y, z] = meshgrid(1:size(app.L1,2), 1:size(app.L1,1), 1:size(app.L1,3));
            app.lesionPatches = {};
            maxLesionIndex = max([max(app.L1(:)), max(app.L2(:))]);

            for idx = 1:maxLesionIndex
                drawLesion(app, idx, x, y, z);
            end
        end

        function drawLesion(app, lesionIndex, x, y, z)
            % Draw lesion for L1
            if any(app.L1(:) == lesionIndex)
                drawMat = app.L1 == lesionIndex;
                if nnz(drawMat) > 0 % Check if non-empty
                    [f, v] = isosurface(x, y, z, drawMat, 0.5);
                    lesionPatch = patch(app.LesionVisualizationAxes, 'Faces', f, 'Vertices', v, 'FaceColor', 'red', 'FaceAlpha', 0, 'EdgeColor', 'none'); % Initially invisible
                    app.lesionPatches{end+1} = {lesionPatch, 1, lesionIndex}; % Time point 1
                end
            end

            % Repeat for L2 with appropriate checks and adjustments
            if any(app.L2(:) == lesionIndex)
                drawMat = app.L2 == lesionIndex;
                if nnz(drawMat) > 0
                    [f, v] = isosurface(x, y, z, drawMat, 0.5);
                    lesionPatch = patch(app.LesionVisualizationAxes, 'Faces', f, 'Vertices', v, 'FaceColor', 'blue', 'FaceAlpha', 0, 'EdgeColor', 'none'); % Initially invisible
                    app.lesionPatches{end+1} = {lesionPatch, 2, lesionIndex}; % Time point 2
                end
            end
        end

        function updateLesionVisibility(app, selectedLesionIndex, timePoint)

            switchState = app.LesionVisibilitySwitch.Value; % Assuming 'One' or 'All' are the switch states

            for i = 1:length(app.lesionPatches)
                patchInfo = app.lesionPatches{i};
                lesionPatch = patchInfo{1}; % The patch object
                lesionTimePoint = patchInfo{2}; % Time point of the lesion

                % Apply visibility rules based on the switch state and provided parameters
                if strcmp(switchState, 'All') && lesionTimePoint == timePoint
                    lesionPatch.FaceAlpha = 1; % Make all lesions for the specified time point visible
                else
                    if patchInfo{3} == selectedLesionIndex && lesionTimePoint == timePoint
                        lesionPatch.FaceAlpha = 1; % Make the selected lesion visible if in 'One' state
                    else
                        lesionPatch.FaceAlpha = 0; % Make other lesions invisible
                    end
                end
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.whichT2 = evalin('base', 't2tp2'); % T2 Flair image at time point 2
            app.x0 = 100;
            app.y0 = 100;
            app.z0 = 100;
            % Initialize with a random matrix of size 260x311x260
            app.whichT2 = rand(260, 311, 260);

            % Initial call to display the image
            updateImage(app);
            app.Spinner.Limits = [1 size(app.whichT2, 3)];
            app.Spinner_2.Limits = [1 size(app.whichT2, 2)];
            app.Spinner_3.Limits = [1 size(app.whichT2, 1)];

            % Call updateImage to display the initial random matrix or handle as needed
            updateImage(app);
            app.LesionVisibilitySwitch.Value = 'One';
            app.CurrentImageIndex = 1;

            app.ImageSwitchTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.5, ...
                'TimerFcn', @(~,~)toggleT2Image(app));

               
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.x0 = app.Spinner.Value;
            updateImage(app);
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.y0 = app.Spinner_2.Value;
            updateImage(app);            
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            app.z0 = app.Spinner_3.Value;
            updateImage(app);
        end

        % Button pushed function: LoadT2FlairTP1Button
        function LoadT2FlairTP1ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select the T2 Flair Time Point 1 File');
            if isequal(file, 0) || isequal(path, 0)
                disp('User selected Cancel');
            else
                fullPath = fullfile(path, file);
                app.T2FlairTP1 = niftiread(fullPath); % Load the image data
                app.T2FlairTP1 = flip(app.T2FlairTP1,2);app.T2FlairTP1 = flip(app.T2FlairTP1,3);
                app.LoadT2FlairTP1Button.BackgroundColor = [0.5 0.5 0.5]; % Change button color to dark gray to indicate loading

                % Automatically update whichT2 for immediate feedback
                app.whichT2 = app.T2FlairTP1;

                % If T2FlairTP2 is not loaded yet, mirror T2FlairTP1
                if isempty(app.T2FlairTP2)
                    app.T2FlairTP2 = app.T2FlairTP1;
                end

                app.whichT2 = app.T2FlairTP1; 
                app.CurrentImageIndex = 1; 
                updateImage(app); % Refresh to show the loaded image
            end
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)
            % Check if the timer has been initialized
            if isempty(app.ImageSwitchTimer)
                disp('Timer is not initialized.');
                return; % Exit the function if the timer isn't ready
            end

            % Proceed with checking the timer's state
            if strcmp(app.ImageSwitchTimer.Running, 'off')
                start(app.ImageSwitchTimer);
                app.PlayPauseButton.Text = 'Pause';
            else
                stop(app.ImageSwitchTimer);
                app.PlayPauseButton.Text = 'Play';
            end
        end

        % Button pushed function: LoadT2FlairTP2Button
        function LoadT2FlairTP2ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select the T2 Flair Time Point 2 File');
            if isequal(file, 0) || isequal(path, 0)
                disp('User selected Cancel');
            else
                fullPath = fullfile(path, file);
                app.T2FlairTP2 = niftiread(fullPath); % Load the image data
                app.T2FlairTP2 = flip(app.T2FlairTP2,2);app.T2FlairTP2 = flip(app.T2FlairTP2,3);

                app.LoadT2FlairTP2Button.BackgroundColor = [0.5 0.5 0.5]; % Change button color to dark gray to indicate loading

                % If T2FlairTP1 is not loaded yet, mirror T2FlairTP2
                if isempty(app.T2FlairTP1)
                    app.T2FlairTP1 = app.T2FlairTP2;
                end

                app.whichT2 = app.T2FlairTP2; 
                app.CurrentImageIndex = 2; 
                updateImage(app); % Refresh to show the loaded image
            end
        end

        % Button pushed function: Loadlesion1Button
        function Loadlesion1ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select Lesion 1 File');
            if ~isequal(file, 0)
                app.Lesion1 = niftiread(fullfile(path, file));
               % app.Lesion1 = flip(app.Lesion1,2);app.Lesion1 = flip(app.Lesion1,3);
                app.Loadlesion1Button.BackgroundColor = [0.5 0.5 0.5];
            end
        end

        % Button pushed function: Loadlesion2Button
        function Loadlesion2ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select Lesion 2 File');
            if ~isequal(file, 0)
                app.Lesion2 = niftiread(fullfile(path, file));
                %app.Lesion2 = flip(app.Lesion2,2);app.Lesion2 = flip(app.Lesion2,3);

                app.Loadlesion2Button.BackgroundColor = [0.5 0.5 0.5];
                % If both lesions are loaded, you can call the analysis
                if ~isempty(app.Lesion1) && ~isempty(app.Lesion2)
                    analyzeLesions(app); 
                    drawLesions(app);
                end
            end
        end

        % Button pushed function: LoadBrainMaskButton
        function LoadBrainMaskButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select Brain Mask File');
            if isequal(file, 0) || isequal(path, 0)
                disp('User selected Cancel');
            else
                brainMaskFullPath = fullfile(path, file);
                app.brainMask = niftiread(brainMaskFullPath);
                app.LoadBrainMaskButton.BackgroundColor = [0.5 0.5 0.5];
                %app.brainMask = flip(app.brainMask,2);app.brainMask = flip(app.brainMask,3);
                drawBrainMask(app);
            end
        end

        % Value changed function: LesionIndexSpinner
        function LesionIndexSpinnerValueChanged(app, event)
            app.LesionVisibilitySwitch.Value = 'One';
            updateLesionVisibility(app, app.LesionIndexSpinner.Value, app.CurrentImageIndex);
            app.Spinner.Value = app.lesionCenter(app.LesionIndexSpinner.Value,1);
            app.Spinner_2.Value = app.lesionCenter(app.LesionIndexSpinner.Value,2);
            app.Spinner_3.Value = app.lesionCenter(app.LesionIndexSpinner.Value,3);
        end

        % Button pushed function: BIDSButton
        function BIDSButtonPushed(app, event)
            % Open folder selection dialog
            bidsDir = uigetdir();
            if isequal(bidsDir, 0)
                disp('User selected Cancel');
                return;
            end

            % Define your file search patterns
            whichScan = 'Output_2D';
            segDir = 'output_segmentation';

            % Attempt to load each file
            try
                % T2FlairTP1
                t2FlairTP1File = dir(fullfile(bidsDir, whichScan, '*', segDir, 'tp001', 'mode02_bias_corrected.nii.gz'));
                if ~isempty(t2FlairTP1File)
                    app.T2FlairTP1 = niftiread(fullfile(t2FlairTP1File.folder, t2FlairTP1File.name));
                    app.T2FlairTP1 = flip(app.T2FlairTP1,2);app.T2FlairTP1 = flip(app.T2FlairTP1,3);
                    app.LoadT2FlairTP1Button.BackgroundColor = [0.5, 0.5, 0.5]; % Change color to indicate loading
                end

                % T2FlairTP2
                t2FlairTP2File = dir(fullfile(bidsDir, whichScan, '*', segDir, 'tp002', 'mode02_bias_corrected.nii.gz'));
                if ~isempty(t2FlairTP2File)
                    app.T2FlairTP2 = niftiread(fullfile(t2FlairTP2File.folder, t2FlairTP2File.name));
                    app.T2FlairTP2 = flip(app.T2FlairTP2,2);app.T2FlairTP2 = flip(app.T2FlairTP2,3);
                    app.LoadT2FlairTP2Button.BackgroundColor = [0.5, 0.5, 0.5];
                end

                app.whichT2 = app.T2FlairTP2; 
                app.CurrentImageIndex = 2; 
                updateImage(app);

                % BrainMask
                brainMaskFile = dir(fullfile(bidsDir, whichScan, '*', 'dummy', 'T1w', 'T1w_acpc_brain_mask.nii.gz'));
                if ~isempty(brainMaskFile)
                    app.brainMask = niftiread(fullfile(brainMaskFile.folder, brainMaskFile.name));
                    app.LoadBrainMaskButton.BackgroundColor = [0.5, 0.5, 0.5];
                    drawBrainMask(app);
                end

                % Lesion1
                lesion1File = dir(fullfile(bidsDir, whichScan, '*', segDir, 'tp001', 'tp001_lesions_manual.nii.gz'));
                if ~isempty(lesion1File)
                    app.Lesion1 = niftiread(fullfile(lesion1File.folder, lesion1File.name));
                    app.Loadlesion1Button.BackgroundColor = [0.5, 0.5, 0.5];
                end

                % Lesion2
                lesion2File = dir(fullfile(bidsDir, whichScan, '*', segDir, 'tp002', 'tp002_lesions_manual.nii.gz'));
                if ~isempty(lesion2File)
                    app.Lesion2 = niftiread(fullfile(lesion2File.folder, lesion2File.name));
                    app.Loadlesion2Button.BackgroundColor = [0.5, 0.5, 0.5];
                end

                if ~isempty(app.Lesion1) && ~isempty(app.Lesion2)
                    analyzeLesions(app); 
                    drawLesions(app);
                end

            catch ME
                disp(['Error loading files: ', ME.message]);
            end
        end

        % Value changed function: LesionVisibilitySwitch
        function LesionVisibilitySwitchValueChanged(app, event)
         updateLesionVisibility(app, app.LesionIndexSpinner.Value, app.CurrentImageIndex);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.LesionVisualizationAxes = uiaxes(app.UIFigure);
            ylabel(app.LesionVisualizationAxes, 'Y')
            app.LesionVisualizationAxes.XTick = [];
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadT2FlairTP1Button = uibutton(app.Panel_2, 'push');
            app.LoadT2FlairTP1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadT2FlairTP1ButtonPushed, true);
            app.LoadT2FlairTP1Button.Text = 'Load T2 Flair Time Point 1';
            app.LoadT2FlairTP2Button = uibutton(app.Panel_2, 'push');
            app.LoadT2FlairTP2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadT2FlairTP2ButtonPushed, true);
            app.LoadT2FlairTP2Button.Text = 'Load T2 Flair Time Point 2';
            app.Loadlesion1Button = uibutton(app.Panel_2, 'push');
            app.Loadlesion1Button.ButtonPushedFcn = createCallbackFcn(app, @Loadlesion1ButtonPushed, true);
            app.Loadlesion1Button.Text = 'Load lesion 1';
            app.Loadlesion2Button = uibutton(app.Panel_2, 'push');
            app.Loadlesion2Button.ButtonPushedFcn = createCallbackFcn(app, @Loadlesion2ButtonPushed, true);
            app.Loadlesion2Button.Text = 'Load lesion 2';
            app.LoadBrainMaskButton = uibutton(app.Panel_2, 'push');
            app.LoadBrainMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBrainMaskButtonPushed, true);
            app.LoadBrainMaskButton.Text = 'Mask';
            app.BIDSButton = uibutton(app.Panel_2, 'push');
            app.BIDSButton.ButtonPushedFcn = createCallbackFcn(app, @BIDSButtonPushed, true);
            app.BIDSButton.Text = 'BIDS';
            app.PlayPauseButton = uibutton(app.UIFigure, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Text = 'Play';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LesionIndexSpinner = uispinner(app.UIFigure);
            app.LesionIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @LesionIndexSpinnerValueChanged, true);
            app.LesionVisibilitySwitch = uiswitch(app.UIFigure, 'slider');
            app.LesionVisibilitySwitch.Items = {'One', 'All'};
            app.LesionVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @LesionVisibilitySwitchValueChanged, true);
            app.LesionVisibilitySwitch.Value = 'One';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lesionView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end