classdef osama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Slider                matlab.ui.control.Slider
        Label                 matlab.ui.control.Label
        CoordinatesTextField  matlab.ui.control.EditField
        EditFieldLabel        matlab.ui.control.Label
        Slider_3              matlab.ui.control.Slider
        Label_3               matlab.ui.control.Label
        Slider_2              matlab.ui.control.Slider
        Label_2               matlab.ui.control.Label
        UIAxes_6              matlab.ui.control.UIAxes
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = public)
        my3DData % Description
        zoomLevel double = 5 % Default zoom level
        zoomWindowSize double = 50 % Default zoom window size, adjust as needed
        currentX double = 1 % Current X position
        currentY double = 1 % Current Y position
        currentZ double = 1 % Current Z position
    end

    methods (Access = private)
        function initSlidersAndDisplayFirstSlice(app)
            % Assuming the 3rd dimension is the 'depth' for axial slices
            depth = size(app.my3DData, 3);

            % Initialize sliders
            app.Slider.Limits = [1 depth];
            app.Slider.Value = 1;
            app.Slider_2.Limits = [1 depth]; % Adjust according to your data orientation
            app.Slider_2.Value = 1;
            app.Slider_3.Limits = [1 depth]; % Adjust according to your data orientation
            app.Slider_3.Value = 1;

            % Display the first slice in each UIAxes
            % Adjust these commands according to the orientation of your slices
            imagesc(app.UIAxes, squeeze(app.my3DData(:, :, 1)));
            imagesc(app.UIAxes_2, squeeze(app.my3DData(:, :, 1))); % Adjust for correct view
            imagesc(app.UIAxes_3, squeeze(app.my3DData(:, :, 1))); % Adjust for correct view

            % Set the colormap for each axes (if desired)
            colormap(app.UIAxes, 'gray');
            colormap(app.UIAxes_2, 'gray');
            colormap(app.UIAxes_3, 'gray');
        end

        function updateSagittalView(app, x)
            sagittalSlice = squeeze(app.my3DData(x, :, :));
            imagesc(app.UIAxes_3, sagittalSlice'); % Assuming UIAxes_3 displays the sagittal view
            colormap(app.UIAxes_3, 'gray');
            app.UIAxes_3.XLim = [0 size(app.my3DData,2)];
            app.UIAxes_3.YLim = [0 size(app.my3DData,1)];
        end

        function updateCoronalView(app, y)
            coronalSlice = squeeze(app.my3DData(:, y, :));
            imagesc(app.UIAxes_2, coronalSlice'); % Assuming UIAxes_2 displays the coronal view
            colormap(app.UIAxes_2, 'gray');
            app.UIAxes.XLim = [0 size(app.my3DData,3)];
            app.UIAxes.YLim = [0 size(app.my3DData,1)];

        end

        function updateAxialView(app, z)
            axialSlice = squeeze(app.my3DData(:, :, z));
            imagesc(app.UIAxes, axialSlice); % Assuming UIAxes displays the axial view
            colormap(app.UIAxes, 'gray');
            app.UIAxes.XLim = [0 size(app.my3DData,2)];
            app.UIAxes.YLim = [0 size(app.my3DData,3)];
        end

        function updateAxialViewZoomed(app, x, y, z)
            [height, width, ~] = size(app.my3DData);

            % Calculate bounds, ensuring they stay within the image dimensions
            xMin = max(1, x - app.zoomWindowSize);
            xMax = min(width, x + app.zoomWindowSize);
            yMin = max(1, y - app.zoomWindowSize);
            yMax = min(height, y + app.zoomWindowSize);

            % Adjust for the axial view: defined by the x-y plane at the current z
            zoomedSlice = app.my3DData(xMin:xMax, yMin:yMax, z);

            % Display the zoomed slice
            imagesc(app.UIAxes_4, zoomedSlice); % Assuming UIAxes_4 is for the zoomed axial view
            colormap(app.UIAxes_4, 'gray');
            axis(app.UIAxes_4, 'equal'); % Maintain aspect ratio

            updateCoronalView(app, y);
            updateAxialView(app, z);
            updateSagittalView(app, x);

            rectangle('Position', [app.currentY-app.zoomWindowSize, app.currentZ-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes_3);
            rectangle('Position', [app.currentX-app.zoomWindowSize, app.currentZ-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes_2);
            rectangle('Position', [app.currentY-app.zoomWindowSize, app.currentX-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes);

        end

        function updateCoronalViewZoomed(app, x, y, z)
            [height, ~, depth] = size(app.my3DData);

            % Calculate bounds, ensuring they stay within the image dimensions
            xMin = max(1, x - app.zoomWindowSize);
            xMax = min(height, x + app.zoomWindowSize); % Use height for x-dimension due to orientation
            zMin = max(1, z - app.zoomWindowSize);
            zMax = min(depth, z + app.zoomWindowSize);

            % Extract and display the zoomed coronal slice
            zoomedSlice = squeeze(app.my3DData(xMin:xMax, y, zMin:zMax));
            imagesc(app.UIAxes_5, zoomedSlice'); % Assuming UIAxes_5 is for the zoomed coronal view
            colormap(app.UIAxes_5, 'gray');
            axis(app.UIAxes_5, 'equal'); % Maintain aspect ratio

            updateCoronalView(app, y);
            updateAxialView(app, z);
            updateSagittalView(app, x);

            rectangle('Position', [app.currentY-app.zoomWindowSize, app.currentZ-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes_3);
            rectangle('Position', [app.currentX-app.zoomWindowSize, app.currentZ-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes_2);
            rectangle('Position', [app.currentY-app.zoomWindowSize, app.currentX-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes);

        end

        function updateSagittalViewZoomed(app, x, y, z)
            [~, width, depth] = size(app.my3DData);

            % Calculate bounds, ensuring they stay within the image dimensions
            yMin = max(1, y - app.zoomWindowSize);
            yMax = min(width, y + app.zoomWindowSize); % Use width for y-dimension due to orientation
            zMin = max(1, z - app.zoomWindowSize);
            zMax = min(depth, z + app.zoomWindowSize);

            % Extract and display the zoomed sagittal slice
            zoomedSlice = squeeze(app.my3DData(x, yMin:yMax, zMin:zMax));
            imagesc(app.UIAxes_6, zoomedSlice'); % Assuming UIAxes_6 is for the zoomed sagittal view
            colormap(app.UIAxes_6, 'gray');
            axis(app.UIAxes_6, 'equal'); % Maintain aspect ratio

            updateCoronalView(app, y);
            updateAxialView(app, z);
            updateSagittalView(app, x);
            
            rectangle('Position', [app.currentY-app.zoomWindowSize, app.currentZ-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes_3);
            rectangle('Position', [app.currentX-app.zoomWindowSize, app.currentZ-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes_2);
            rectangle('Position', [app.currentY-app.zoomWindowSize, app.currentX-app.zoomWindowSize, 2*app.zoomWindowSize, 2*app.zoomWindowSize], 'EdgeColor', 'r', 'Parent', app.UIAxes);

        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                   
            % Load 3D data here
            % loadedData = load('brainMask.mat'); % Replace 'my3DDataFile.mat' with your actual file
            % app.my3DData = loadedData.brainMask; % Replace 'variableName' with the actual variable name
            loadedData = load('t2tp2.mat'); % Replace 'my3DDataFile.mat' with your actual file
            app.my3DData = loadedData.t2tp2;
            
            initSlidersAndDisplayFirstSlice(app);

            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes_2.DataAspectRatio = [1 1 1];
            app.UIAxes_3.DataAspectRatio = [1 1 1];

            % Optionally, adjust PlotBoxAspectRatio if needed
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes_2.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes_3.PlotBoxAspectRatio = [1 1 1];

            app.UIAxes.Color = [0 0 0];
            app.UIAxes_2.Color = [0 0 0];
            app.UIAxes_3.Color = [0 0 0];
            app.UIAxes_4.Color = [0 0 0];
            app.UIAxes_5.Color = [0 0 0];
            app.UIAxes_6.Color = [0 0 0];


        end

        % Value changed function: CoordinatesTextField
        function CoordinatesTextFieldValueChanged(app, event)

            coordinatesStr = app.CoordinatesTextField.Value; % Get the string from the text field
            coordinates = str2num(coordinatesStr); % Convert string to numbers

            if length(coordinates) == 3
                x = coordinates(1);
                y = coordinates(2);
                z = coordinates(3);

                % Update the zoomed-in views
                updateAxialViewZoomed(app, x, y, z);
                updateCoronalViewZoomed(app, x, y, z);
                updateSagittalViewZoomed(app, x, y, z);

                % Optionally, adjust sliders to reflect these coordinates
                app.Slider.Value = z; % Assuming Slider controls the z-axis (axial view)
                app.Slider_2.Value = y; % and so on for y and x...
                app.Slider_3.Value = x;
            else
                % Handle invalid input
            end

        end

        % Value changed function: Slider, Slider_2, Slider_3
        function SliderValueChanged(app, event)

            app.currentZ = round(app.Slider.Value);
            app.currentY = round(app.Slider_2.Value);
            app.currentX = round(app.Slider_3.Value);
            % Update the text field
            app.CoordinatesTextField.Value = sprintf('%d %d %d', app.currentX, app.currentY, app.currentZ);
            % Optionally update views here if needed
            updateAxialViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateCoronalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateSagittalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            updateCoronalView(app, round(event.Value));
            app.currentZ = round(app.Slider.Value);
            app.currentY = round(app.Slider_2.Value);
            app.currentX = round(app.Slider_3.Value);
            updateAxialViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateCoronalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateSagittalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            updateSagittalView(app, round(event.Value));
            app.currentZ = round(app.Slider.Value);
            app.currentY = round(app.Slider_2.Value);
            app.currentX = round(app.Slider_3.Value);
            updateAxialViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateCoronalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateSagittalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            
        end

        % Value changing function: Slider
        function SliderValueChanging2(app, event)
            updateAxialView(app, round(event.Value));
            app.currentZ = round(app.Slider.Value);
            app.currentY = round(app.Slider_2.Value);
            app.currentX = round(app.Slider_3.Value);
            updateAxialViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateCoronalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            updateSagittalViewZoomed(app, app.currentX, app.currentY, app.currentZ);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.CoordinatesTextField = uieditfield(app.UIFigure, 'text');
            app.CoordinatesTextField.ValueChangedFcn = createCallbackFcn(app, @CoordinatesTextFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = osama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end